classdef reflectance_fatdepth_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        FatdepthlowerthresholdSlider  matlab.ui.control.Slider
        FatdepthlowerthresholdSliderLabel  matlab.ui.control.Label
        FatdepthupperthresholdSlider  matlab.ui.control.Slider
        FatdepthupperthresholdSliderLabel  matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        reflectanceCube = [];
        scalarAttribute = [];
        numBands = [];
        scalarAttributeMax = [];
        scalarAttributeMin = [];
        scalarPlotMask = [];
        reflectanceMaskedList = [];
        ReflectanceAxes;
    end
    
    methods (Access = private)
        
        function plotreflectancestdscalarattribute(app)
            
            meanCurve = mean(app.reflectanceMaskedList, 1);  
            stdDev = std(app.reflectanceMaskedList, 0, 1);  
            
            % Clear the existing plot
            cla(app.UIAxes);

            % Plot the mean curve
            plot(app.UIAxes, meanCurve, 'b');

            hold(app.UIAxes, 'on');

            % Plot the shaded region (standard deviation bounds)
            x = 1:length(meanCurve);
            fill(app.UIAxes, [x fliplr(x)], [meanCurve + stdDev fliplr(meanCurve - stdDev)], 'b', 'FaceAlpha', 0.3, 'EdgeColor', 'none');

            ylabel(app.UIAxes, 'Reflectance');
            xlabel(app.UIAxes, 'Hyperspectral band number (change to wavelength)');
            grid(app.UIAxes, 'on');
            xlim(app.UIAxes, [0, app.numBands]);
            hold(app.UIAxes, 'off');

        end

        function newscalarplotmask(app, minValue, maxValue)
            
            app.scalarPlotMask = (app.scalarAttribute >= minValue) & (app.scalarAttribute <= maxValue);

            [rows, cols] = find(app.scalarPlotMask);

            app.reflectanceMaskedList = zeros(length(rows), app.numBands);

            for b = 1:app.numBands
                curReflectanceList = impixel(app.reflectanceCube(:,:,b), cols, rows);
                app.reflectanceMaskedList(:, b) = curReflectanceList(:,1);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, reflectancecubeX, fatdepthcubeY)
            % Get the data to the input:
            app.reflectanceCube = reflectancecubeX;
            app.scalarAttribute = fatdepthcubeY;

            [numV, numU, numBands] = size(app.reflectanceCube);
            
            app.numBands = numBands;

            % Store the the app members:
            app.scalarAttributeMax = max(app.scalarAttribute, [], "all");
            app.scalarAttributeMin = min(app.scalarAttribute, [], "all");

            % Set up the slider's Min Max Value:
            app.FatdepthlowerthresholdSlider.Limits = [app.scalarAttributeMin, app.scalarAttributeMax];
            app.FatdepthupperthresholdSlider.Limits = [app.scalarAttributeMin, app.scalarAttributeMax];

            % Set up the initial values
            app.FatdepthlowerthresholdSlider.Value = app.scalarAttributeMin;
            app.FatdepthupperthresholdSlider.Value = app.scalarAttributeMax;

        end

        % Value changed function: FatdepthupperthresholdSlider
        function FatdepthupperthresholdSliderValueChanged(app, event)
            upperValue = app.FatdepthupperthresholdSlider.Value;
            lowerValue = app.FatdepthlowerthresholdSlider.Value;

            % Ensure the lower value is always <= upper value
            if lowerValue > upperValue
                lowerValue = upperValue;
                app.FatDepthLowerSlider.Value = lowerValue;
            end

            newscalarplotmask(app, lowerValue, upperValue);
            plotreflectancestdscalarattribute(app);
        end

        % Value changed function: FatdepthlowerthresholdSlider
        function FatdepthlowerthresholdSliderValueChanged(app, event)
            upperValue = app.FatdepthupperthresholdSlider.Value;
            lowerValue = app.FatdepthlowerthresholdSlider.Value;

            % Ensure the lower value is always <= upper value
            if lowerValue > upperValue
                lowerValue = upperValue;
                app.FatDepthLowerSlider.Value = lowerValue;
            end

            newscalarplotmask(app, lowerValue, upperValue);
            plotreflectancestdscalarattribute(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FatdepthupperthresholdSliderLabel = uilabel(app.LeftPanel);
            app.FatdepthupperthresholdSliderLabel.Text = 'Fat depth upper threshold';
            app.FatdepthupperthresholdSlider = uislider(app.LeftPanel);
            app.FatdepthupperthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @FatdepthupperthresholdSliderValueChanged, true);
            app.FatdepthlowerthresholdSliderLabel = uilabel(app.LeftPanel);
            app.FatdepthlowerthresholdSliderLabel.Text = 'Fat depth lower threshold';
            app.FatdepthlowerthresholdSlider = uislider(app.LeftPanel);
            app.FatdepthlowerthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @FatdepthlowerthresholdSliderValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Reflectance with STD for upper and lower fat depth')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reflectance_fatdepth_viewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end